classdef SPM_SimArch_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LoadSourceCodeButton           matlab.ui.control.Button
        SinglePhaseMotorSimulationArchitectureLabel  matlab.ui.control.Label
        OpenConfigurationScriptButton  matlab.ui.control.Button
        CompileMCIcodeButton           matlab.ui.control.Button
        RunSimulationButton            matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function AmplitudeSliderValueChanged(app, event)
            value = app.AmplitudeSlider.Value;
            plot(app.UIAxes, value*peaks);
            app.UIAxes.YLim = [-1000 1000];
        end

        % Button pushed function: LoadSourceCodeButton
        function LoadSourceCodeButtonPushed(app, event)
            % hObject    handle to cntrl_source (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Root_Path=uigetdir('C:\');
            if(Root_Path==0)   % Handler if Cancel is Pressed while selecting Path
                msgbox('Set Motor Control Source Code Path');
                Motor_Control_Source='Not Set';
            else
                Motor_Control_Source='Set';
            end
            assignin('base', 'Root_Path', Root_Path);
            %evalin('base','Paths.Root_Path=Root_Path;');
            %evalin('base','clear Root_Path;');
            %Object_Path='';
            %assignin('base', 'Object_Path', Object_Path);
            %evalin('base','Paths.Object_Path=Object_Path;');
            %evalin('base','clear Object_Path;');
            %assignin('base', 'Motor_Control_Source', Motor_Control_Source);
            %evalin('base','config.Motor_Control_Source=Motor_Control_Source;');
            %evalin('base','clear Motor_Control_Source');
            %updateModelWS('MCU_Simulation_Architecture');
        end

        % Button pushed function: OpenConfigurationScriptButton
        function OpenConfigurationScriptButtonPushed(app, event)
            open("tools\Config_UI\SPM_SimArch_Config.m");
        end

        % Button pushed function: CompileMCIcodeButton
        function CompileMCIcodeButtonPushed(app, event)
            run("Compile_Link_MCI.m");
        end

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            sim("Single_Phase_Motor_SimArch.slx");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadSourceCodeButton = uibutton(app.UIFigure, 'push');
            app.LoadSourceCodeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSourceCodeButtonPushed, true);
            app.LoadSourceCodeButton.Text = 'Load Source Code';
            app.SinglePhaseMotorSimulationArchitectureLabel = uilabel(app.UIFigure);
            app.SinglePhaseMotorSimulationArchitectureLabel.Text = 'Single Phase Motor Simulation Architecture';
            app.OpenConfigurationScriptButton = uibutton(app.UIFigure, 'push');
            app.OpenConfigurationScriptButton.ButtonPushedFcn = createCallbackFcn(app, @OpenConfigurationScriptButtonPushed, true);
            app.OpenConfigurationScriptButton.Text = 'Open Configuration Script';
            app.CompileMCIcodeButton = uibutton(app.UIFigure, 'push');
            app.CompileMCIcodeButton.ButtonPushedFcn = createCallbackFcn(app, @CompileMCIcodeButtonPushed, true);
            app.CompileMCIcodeButton.Text = 'Compile MCI code';
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPM_SimArch_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end