classdef Sim_Arch_Config_UI_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                     matlab.ui.Figure
        InverterVariants            matlab.ui.container.ButtonGroup
        MeanValueLosses             matlab.ui.control.RadioButton
        IdealInverter               matlab.ui.control.RadioButton
        InverterVariantsLabel       matlab.ui.control.Label
        SettingFile_Panel           matlab.ui.container.Panel
        SettingFile_Enable          matlab.ui.control.CheckBox
        SettingFile_Source          matlab.ui.control.Button
        SettingFileLabel            matlab.ui.control.Label
        MessageArea                 matlab.ui.control.TextArea
        LoadSelectionLabel          matlab.ui.control.Label
        LoadSelectionTab            matlab.ui.container.TabGroup
        DynoTab                     matlab.ui.container.Tab
        LoadprofilecanbedefinedonthemodelLabel  matlab.ui.control.Label
        WasherTab                   matlab.ui.container.Tab
        WasherMechanicsTab          matlab.ui.container.TabGroup
        HATab                       matlab.ui.container.Tab
        BeltRatioHA                 matlab.ui.control.NumericEditField
        BeltRatioEditFieldLabel     matlab.ui.control.Label
        HAModeButtonGroup           matlab.ui.container.ButtonGroup
        SpinButton                  matlab.ui.control.RadioButton
        TumblingButton              matlab.ui.control.RadioButton
        Drum                        matlab.ui.container.ButtonGroup
        drumenable                  matlab.ui.control.RadioButton
        drumdisable                 matlab.ui.control.RadioButton
        text3                       matlab.ui.control.Label
        drummass                    matlab.ui.control.EditField
        text5                       matlab.ui.control.Label
        inertiamass                 matlab.ui.control.EditField
        text6                       matlab.ui.control.Label
        text8                       matlab.ui.control.Label
        drumradius                  matlab.ui.control.EditField
        text9                       matlab.ui.control.Label
        text10                      matlab.ui.control.Label
        thickness                   matlab.ui.control.EditField
        text11                      matlab.ui.control.Label
        druminertia                 matlab.ui.control.Button
        drumJ                       matlab.ui.control.EditField
        text4                       matlab.ui.control.Label
        MechList_HA                 matlab.ui.control.ListBox
        VATab                       matlab.ui.container.Tab
        BeltRatioVA                 matlab.ui.control.NumericEditField
        BeltRatioEditField_2Label   matlab.ui.control.Label
        GearRatioEditField          matlab.ui.control.NumericEditField
        GearRatioEditFieldLabel     matlab.ui.control.Label
        MechList_VA                 matlab.ui.control.ListBox
        VaModeButtonGroup           matlab.ui.container.ButtonGroup
        spin                        matlab.ui.control.RadioButton
        agitation                   matlab.ui.control.RadioButton
        CompressorTab               matlab.ui.container.Tab
        uibuttongroup25             matlab.ui.container.ButtonGroup
        Ts                          matlab.ui.control.Label
        Tstator                     matlab.ui.control.EditField
        Tr                          matlab.ui.control.Label
        Trotor                      matlab.ui.control.EditField
        DegC                        matlab.ui.control.Label
        text20                      matlab.ui.control.Label
        MotorSelectionLabel         matlab.ui.control.Label
        TabGroupMotor               matlab.ui.container.TabGroup
        BPMTab                      matlab.ui.container.Tab
        Enable_High_Speed           matlab.ui.control.CheckBox
        uibuttongroup32_2           matlab.ui.container.ButtonGroup
        BPM_Model_Executable_Group  matlab.ui.container.ButtonGroup
        SF                          matlab.ui.control.RadioButton
        FMU                         matlab.ui.control.RadioButton
        Enable_Motor_Power_Loss     matlab.ui.control.CheckBox
        uibuttongroup32             matlab.ui.container.ButtonGroup
        MotorList_BPM               matlab.ui.control.ListBox
        BPM_Motor_Type_Group        matlab.ui.container.ButtonGroup
        gbpm                        matlab.ui.control.RadioButton
        sbpm                        matlab.ui.control.RadioButton
        ddbpm                       matlab.ui.control.RadioButton
        CIMTab                      matlab.ui.container.Tab
        MotorList_CIM               matlab.ui.control.ListBox
        InputCircuitVariantsLabel   matlab.ui.control.Label
        MCISourceCodeLabel          matlab.ui.control.Label
        SimulationModeLabel         matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        cntrl_source                matlab.ui.control.Button
        RectifierButtonGroup        matlab.ui.container.ButtonGroup
        RectFreqGroup               matlab.ui.container.ButtonGroup
        rect_step_2k                matlab.ui.control.RadioButton
        half_mci_step               matlab.ui.control.RadioButton
        mci_step                    matlab.ui.control.RadioButton
        Rectifier                   matlab.ui.control.RadioButton
        text13                      matlab.ui.control.Label
        DC1                         matlab.ui.control.EditField
        DCBus                       matlab.ui.control.RadioButton
        BoardSelectionLabel         matlab.ui.control.Label
        TabGroup                    matlab.ui.container.TabGroup
        VTab                        matlab.ui.container.Tab
        RectifierList_230           matlab.ui.control.ListBox
        VTab_2                      matlab.ui.container.Tab
        RectifierList_120           matlab.ui.control.ListBox
        V12WaveTab                  matlab.ui.container.Tab
        RectifierList_120half       matlab.ui.control.ListBox
        Motor_Control_Options       matlab.ui.container.ButtonGroup
        Motor_Moving_handler        matlab.ui.control.CheckBox
        ADC_Initialization          matlab.ui.control.CheckBox
        sample_time                 matlab.ui.container.ButtonGroup
        text21                      matlab.ui.control.Label
        Simulation_Sample_Time      matlab.ui.control.EditField
        simulatemodel               matlab.ui.control.Button
        SimulationModeGroup         matlab.ui.container.ButtonGroup
        PIL                         matlab.ui.control.RadioButton
        SIL                         matlab.ui.control.RadioButton
        text2                       matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Sim_Arch_Config_UI_OpeningFcn(app)
%             
            %---- Model Step Size Intialization---%
            evalin('base','TimeSteps=struct;');
            evalin('base','TimeSteps.ModelStep=1/8000;');
            evalin('base','TimeSteps.InputCircuitStep=1/2000;');
%             
            %-- Initialization of the Flag for User Defined MCI  Sampling Frequency ---%
            MCI_Sampling_Frequency='Not Set';
            assignin('base', 'MCI_Sampling_Frequency', MCI_Sampling_Frequency);
            evalin('base','config.MCI_Sampling_Frequency=MCI_Sampling_Frequency;');
            evalin('base','clear MCI_Sampling_Frequency');
%             
%             %---Constant DC-Bus Initialization---%
%             set(app.DCBus,'Value',1);
            app.DCBus.Value = 1;
            evalin('base','InputCircuit=struct;');
            evalin('base','DC_Bus=struct;');
            evalin('base','DC_Bus.Const_Vbus=325;');
%             str=num2str(325);
%             set(app.DC1,'String',str);
            app.DC1.Value = '325';
%             set(app.RectifierList_230,'Enable','on');
%             set(app.DC1,'Enable','on');
            evalin('base','InputCircuit.Vr=0;');
            evalin('base','InputCircuit.freq=0;');
            evalin('base','config.rect_flag=0;');
            evalin('base','config.const_dcbus_flag=1;');
            evalin('base','InputCircuit.Vr=0;');
            evalin('base','InputCircuit.Vdc_i=300;');
            Variant_Input_Circuit=5;
            assignin('base','Variant_Input_Circuit',Variant_Input_Circuit);
            assignin('base','Variant_Inverter', 1);
%             % evalin('base','Sample_Time=1/2000;');
%             
%             
            %---Stator Temperature Initialization----%
            evalin('base','Thermal=struct;');
            evalin('base','Thermal.Tstator=22;');
%             Tstator=evalin('base','Thermal.Tstator');
%             str=num2str(Tstator);
%             set(app.Tstator,'String',str);
            app.Tstator.Value = '22';
%             
            %---- Rotor Temperature Initialization----%
            evalin('base','Thermal.Trotor=22;');
%             Trotor=evalin('base','Thermal.Trotor');
%             str=num2str(Trotor);
%             set(app.Trotor,'String',str);
            app.Trotor.Value = '22';

%             
            %---- Initializaion of Motor Control-----%
            evalin('base','Paths=struct;');
%             set(app.cntrl_source, 'Value',1);
            Root_Path_flag='Source Selected';
            assignin('base','Root_Path_flag', Root_Path_flag);
            evalin('base','config.Root_Path_flag=Root_Path_flag;');
            evalin('base','clear Root_Path_flag');
            Variant_Motor_Control=1;    %-?-%
            assignin('base','Variant_Motor_Control',Variant_Motor_Control);
            Motor_Control_Source='Not set';
            assignin('base', 'Motor_Control_Source', Motor_Control_Source);
            evalin('base','config.Motor_Control_Source=Motor_Control_Source;');
            evalin('base','clear Motor_Control_Source');
            Control_Layer='SIL';                   % Motor Control Variant Initialized to SIL
            assignin('base','Control_Layer', Control_Layer)
            evalin('base','config.Control_Layer=Control_Layer;');
            evalin('base','clear Control_Layer');
%             set(app.obj_src,'Enable','off');
            Variant_Motor_Control=1;    %-?-%
            assignin('base', 'Variant_Motor_Control', Variant_Motor_Control);
            Fast_ADC_Initialization_Flag='Set';      % Fast ADC Initialization Enabled.
            assignin('base', 'Fast_ADC_Initialization_Flag',Fast_ADC_Initialization_Flag);
            evalin('base','config.Fast_ADC_Initialization_Flag=Fast_ADC_Initialization_Flag;');
            evalin('base','clear Fast_ADC_Initialization_Flag');
            Skip_Motor_Moving_Handler_Flag='Set';   % Skip Motor Moving Handler Enabled.
            assignin('base', 'Skip_Motor_Moving_Handler_Flag', Skip_Motor_Moving_Handler_Flag);
            evalin('base','config. Skip_Motor_Moving_Handler_Flag= Skip_Motor_Moving_Handler_Flag;');
            evalin('base','clear  Skip_Motor_Moving_Handler_Flag');
%             set(app.ADC_Initialization,'Value',1);
            app.ADC_Initialization.Value = 1;
%             set(app.Motor_Moving_handler,'Value',1);
            app.Motor_Moving_handler.Value = 1;
%             
            %--Display List of Files in the ListBoxes---%
            %--BPM List--%
            dinfo = dir( fullfile('ParametersFiles\Motor_Parameters\BPM\sBPM', '*.xml') );
            script_names = {dinfo.name};
            app.MotorList_BPM.Items = script_names;
            %--CIM List--%
            dinfo = dir( fullfile('ParametersFiles\Motor_Parameters\CIM', '*.xml') );
            script_names = {dinfo.name};
            app.MotorList_CIM.Items = script_names;
            %--230V boards List--%
            dinfo = dir( fullfile('ParametersFiles\Board_Parameters\HVPS_230VPFC', '*.xml') );
            script_names = {dinfo.name};
            app.RectifierList_230.Items = script_names;
            %--120V boards List--%
            dinfo = dir( fullfile('ParametersFiles\Board_Parameters\HVPS_120V', '*.xml') );
            script_names = {dinfo.name};
            app.RectifierList_120.Items = script_names;
            %--120V half wave boards List--%
            dinfo = dir( fullfile('ParametersFiles\Board_Parameters\HVPS_120V_HalfWave', '*.xml') );
            script_names = {dinfo.name};
            app.RectifierList_120half.Items = script_names;
             %--Mechanical List HA--%
            dinfo = dir( fullfile('ParametersFiles\Mechanical_Parameters\HA', '*.xml') );
            script_names = {dinfo.name};
            app.MechList_HA.Items = script_names;
            %--Mechanical List VA--%
            dinfo = dir( fullfile('ParametersFiles\Mechanical_Parameters\VA\Agitation', '*.xml') );
            script_names = {dinfo.name};
            app.MechList_VA.Items = script_names;
            
            %--Initial Selection of Parameter Files----%
            %---Board---%
            chosen_file = app.RectifierList_230.Value;
            complete_file_R = fullfile( 'ParametersFiles\Board_Parameters\HVPS_230VPFC', chosen_file);
            assignin('base', 'complete_file_R',complete_file_R);
            complete_file_R=evalin('base','complete_file_R');
            evalin('base','Paths.complete_file_R=complete_file_R;');
            evalin('base','clear complete_file_R');
            run_rect_xml(complete_file_R);

assignin('base','Variant_Inverter', 1);
            
            %---Motor---%
            chosen_file = app.MotorList_BPM.Value;
            complete_file_M = fullfile( 'ParametersFiles\Motor_Parameters\BPM\sBPM', chosen_file);
            app.MotorList_BPM.UserData = 'ParametersFiles\Motor_Parameters\BPM\sBPM';
            assignin('base', 'complete_file_M',complete_file_M);
            complete_file_M=evalin('base','complete_file_M');
            evalin('base','Paths.complete_file_M=complete_file_M;');
            evalin('base','clear complete_file_M')
            run_motor_bpm_xml(complete_file_M);
            
            %---Mechanical---%
            chosen_file = app.MechList_HA.Value;
            complete_file_mc = fullfile( 'ParametersFiles\Mechanical_Parameters\HA', chosen_file);
            assignin('base', 'complete_file_mc',complete_file_mc);
            complete_file_mc=evalin('base','complete_file_mc');
            evalin('base','Paths.complete_file_mc=complete_file_mc;');
            evalin('base','clear complete_file_mc');
            run_mech_xml(complete_file_mc);
            %---- Motor Initial Configuration----%
            app.sbpm.Value = 1;
            chosen_dir_M ='ParametersFiles\Motor_Parameters\BPM\sBPM';
            assignin('base', 'chosen_dir_M', chosen_dir_M);
            evalin('base','Paths.chosen_dir_M=chosen_dir_M;');
            evalin('base','clear chosen_dir_M');
%             
%             
%             %set(app.basic_model,'Value',1);
%             set(app.drumdisable,'Value',1)
%             set(app.drummass,'String','');
%             set(app.inertiamass,'String','');
%             set(app.drumradius,'String','');
%             set(app.thickness,'String','');
%             set(app.drumJ,'String','');
%             set(app.drummass,'Enable','off');
%             set(app.inertiamass,'Enable','off');
%             set(app.drumradius,'Enable','off');
%             set(app.thickness,'Enable','off');
%             set(app.drumJ,'Enable','off');
%             set(app.druminertia, 'Enable','off');
%             set(app.mci_step,'Enable','off');
            app.mci_step.Enable = 'off';
%             set(app.rect_step_2k,'Enable','off');
            app.rect_step_2k.Enable = 'off';
%             set(app.mci_step,'Enable','off');
%             set(app.advanced_model,'Enable','on');
%             set(app.mci_step,'Enable','off');
%             set(app.half_mci_step,'Enable','off');
            app.half_mci_step.Enable = 'off';
%             set(app.DCBus,'Value',1);
%             set(app.V120,'Enable','off');
%             set(app.V230,'Enable','off');
%             set(app.HW_120,'Enable','off');
%             set(app.wpfc,'Enable','off');
%             set(app.wopfc,'Enable','off');
%             
%             %-----Initilization of Mechanical Parameters-----%
%             set(app.MechList_VA, 'String', ' ');
%             if(strcmp(Platform,'HA'))
%                 chosen_dir_mc ='ParametersFiles\Mechanical_Parameters\HA';
%                 assignin('base', 'chosen_dir_mc', chosen_dir_mc);
%                 evalin('base','Paths.chosen_dir_mc=chosen_dir_mc;');
%                 evalin('base','clear chosen_dir_mc');
%             else
%                 chosen_dir_mc ='ParametersFiles\Mechanical_Parameters\VA\Spin';
%                 assignin('base', 'chosen_dir_mc', chosen_dir_mc);
%                 evalin('base','Paths.chosen_dir_mc=chosen_dir_mc');
%                 evalin('base','clear chosen_dir_mc');
%             end
%             %--Display List of Files in the ListBox---%
%             
%             set(app.MechList_VA,'Value',1);
%             chosen_dir_mc=evalin('base','Paths.chosen_dir_mc');
%             dinfo = dir( fullfile(chosen_dir_mc, '*.xml') );
%             script_names = {dinfo.name};
%             set(app.MechList_VA, 'String', script_names);
%             set(app.MechList_VA, 'UserData', chosen_dir_mc);
%             %--- Initial Selection of the Parameter File ----%
%             all_choices = get(app.MechList_VA, 'String');
%             choice_idx = get(app.MechList_VA, 'Value');
%             chosen_file = all_choices{choice_idx};
%             complete_file_mc = fullfile( get(app.MechList_VA, 'UserData'), chosen_file);
%             assignin('base', 'complete_file_mc',complete_file_mc);
%             complete_file_mc=evalin('base','complete_file_mc');
%             evalin('base','Paths.complete_file_mc=complete_file_mc;');
%             evalin('base','clear complete_file_mc');
%             if(strcmp(Platform,'HA'))
%                 run_mech_xml(complete_file_mc);
%             else
%                 run_mech_VAspinxml(complete_file_mc);
%             end
%             
%             %---Board Initialization-----%
%             
%             chosen_dir_R = 'ParametersFiles\Board_Parameters\HVPS_230VPFC';
%             assignin('base', 'chosen_dir_R', chosen_dir_R);
%             evalin('base','Paths.chosen_dir_R=chosen_dir_R');
%             evalin('base','clear chosen_dir_R');
%             
%             %--Display List of Files in the ListBox---%
%             chosen_dir_R=evalin('base','Paths.chosen_dir_R');
%             dinfo = dir( fullfile(chosen_dir_R, '*.xml') );
%             script_names = {dinfo.name};
%             set(app.RectifierList_230, 'String', script_names);
%             set(app.RectifierList_230, 'UserData', chosen_dir_R);
%             %-----Initial Parameter File Selection----%
%             
%             set(app.RectifierList_230, 'Value', 1);
%             all_choices = get(app.RectifierList_230, 'String');
%             choice_idx = get(app.RectifierList_230, 'Value');
%             chosen_file = all_choices{choice_idx};
%             complete_file_R = fullfile( get(app.RectifierList_230, 'UserData'), chosen_file);
%             assignin('base', 'complete_file_R',complete_file_R);
%             complete_file_R=evalin('base','complete_file_R');
%             evalin('base','Paths.complete_file_R=complete_file_R;');
%             evalin('base','clear complete_file_R');
%             run_rect_xml(complete_file_R);
            enabled = 'off';
            app.drumJ.Enable = enabled;
            app.drummass.Enable = enabled;
            app.inertiamass.Enable = enabled;
            app.drumradius.Enable = enabled;
            app.thickness.Enable = enabled;
            app.druminertia.Enable = enabled;
            assignin('base', 'Variant_Motor', 4);
%                 evalin('base', 'config.MechanicalVariant=''DynoLoad'';');
%                 evalin('base', 'Motor.Mechanical.TR = 1;');
%                 m_unb=0;
%                 assignin('base', 'm_unb', m_unb);
%                 evalin('base','Mechanical.m_unb=m_unb;');
%                 evalin('base', 'clear m_unb');
%                 r_unb=0;
%                 assignin('base', 'r_unb', r_unb);
%                 evalin('base','Mechanical.r_unb=r_unb;');
%                 evalin('base', 'clear r_unb');
%                 JDrum=0.001;
%                 assignin('base', 'JDrum', JDrum);
%                 evalin('base','Mechanical.JDrum=JDrum;');
%                 evalin('base', 'clear JDrum');
%                 damp=0;
%                 assignin('base', 'damp', damp);
%                 evalin('base','Mechanical.damp=damp;');
%                 evalin('base', 'clear damp');
%                 updateModelWS('MCU_Simulation_Architecture');
            app.drummass.Value = num2str(evalin('base', 'Mechanical.M_D;'));
            app.inertiamass.Value = num2str(evalin('base', 'Mechanical.M;'));
            app.drumradius.Value = num2str(evalin('base', 'Mechanical.R_Drum;'));
            app.thickness.Value = num2str(evalin('base', 'Mechanical.R_J;'));
            app.BeltRatioHA.Value = evalin('base', 'Motor.Mechanical.TR;');
            updateModelWS('MCU_Simulation_Architecture');
            evalin('base', 'Variant_Sensor=0;');
            app.LoadSelectionTab.SelectedTab = app.WasherTab;
%             LoadSelectionTabSelectionChanged(app, 1);
%             app.LoadSelectionTab.SelectedTab = app.DynoTab;
%             LoadSelectionTabSelectionChanged(app, 1);
            %updateModelWS('MCU_Simulation_Architecture');
            
            % Motor Model Executable initialization %
            app.BPM_Model_Executable_Group.SelectedObject = app.SF;
           
            Variant_Motor_Model_Executable = 2;
            assignin('base','Variant_Motor_Model_Executable',Variant_Motor_Model_Executable);
           
            % Setting File initialization %
            set(app.SettingFile_Source,'Enable','off');
            
            Setting_File_Flag = 'Not Set';
            assignin('base', 'Setting_File_Flag',Setting_File_Flag);
            evalin('base','config.Setting_File_Flag=Setting_File_Flag;');
            evalin('base','clear Setting_File_Flag');
            
            Setting_File_Path = '';
            assignin('base', 'Setting_File_Path', Setting_File_Path);
            evalin('base','Paths.Setting_File_Path=Setting_File_Path;');
            evalin('base','clear Setting_File_Path;');
            
            % Rotational Losses initialization %
            % Rot_Loss_init_flag = evalin('base','Motor.Power_Loss.Rot_Loss_Flag');
            % app.Enable_Motor_Power_Loss.Value = Rot_Loss_init_flag;
            app.Enable_Motor_Power_Loss.Value = 0;
                      
        end

        % Value changed function: ADC_Initialization
        function ADC_Initialization_Callback(app, event)
            % hObject    handle to ADC_Initialization (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of ADC_Initialization
            if (app.ADC_Initialization.Value == 1)
%                 Fast_ADC_Initialization_Flag='Set';
%                 assignin('base', 'Fast_ADC_Initialization_Flag','Set');
                evalin('base','config.Fast_ADC_Initialization_Flag=''Set'';');
%                 evalin('base','clear Fast_ADC_Initialization_Flag');
            else
%                 Fast_ADC_Initialization_Flag='Not Set';
%                 assignin('base', 'Fast_ADC_Initialization_Flag',Fast_ADC_Initialization_Flag);
                evalin('base','config.Fast_ADC_Initialization_Flag=''Not Set'';');
%                 evalin('base','clear Fast_ADC_Initialization_Flag');
            end
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: DC1
        function DC1_Callback(app, event)
            % hObject    handle to DC1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of DC1 as text
            %        str2double(get(hObject,'String')) returns contents of DC1 as a double
            Const_Vbus= str2double(app.DC1.Value);
            assignin('base', 'Const_Vbus', Const_Vbus);
            evalin('base','DC_Bus.Const_Vbus=Const_Vbus;');
            clear('base', 'clear Const_Vbus');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: MotorList_BPM
        function MotorList_BPM_Callback(app, event)
            LoadSelectionTabSelectionChanged(app, event);
            %Enable_Motor_Power_Loss_Callback(app, event);
            Enable_Motor_Power_LossValueChanged(app,event);
        end

        % Value changed function: Motor_Moving_handler
        function Motor_Moving_handler_Callback(app, event)
            % hObject    handle to Motor_Moving_handler (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of Motor_Moving_handler
            if (app.Motor_Moving_handler == 1)
                evalin('base','config. Skip_Motor_Moving_Handler_Flag= ''Set'';');
            else
                evalin('base','config. Skip_Motor_Moving_Handler_Flag= ''Not Set'';');
            end
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: RectifierList_230
        function RectifierList_230_Callback(app, event)
            % hObject    handle to RectifierList (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns RectifierList contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from RectifierList
            
            %---- Selection of Parameter File ----%
            chosen_file = app.RectifierList_230.Value;
            complete_file_R = fullfile( 'ParametersFiles\Board_Parameters\HVPS_230VPFC', chosen_file);
            assignin('base', 'complete_file_R',complete_file_R);
            complete_file_R=evalin('base','complete_file_R');
            evalin('base','Paths.complete_file_R=complete_file_R;');
            evalin('base','clear complete_file_R');
            run_rect_xml(complete_file_R);
            
            InverterParams = evalin('base','InverterParams');
            
            if (isempty(fieldnames(InverterParams)))
                
                set(app.MeanValueLosses,'Enable','off');
                app.IdealInverter.Value = true;
            else
                set(app.MeanValueLosses,'Enable','on');
            end


            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: Simulation_Sample_Time
        function Simulation_Sample_Time_Callback(app, event)
            % hObject    handle to Simulation_Sample_Time (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Simulation_Sample_Time as text
            %        str2double(get(hObject,'String')) returns contents of Simulation_Sample_Time as a double
            fs= str2double(app.Simulation_Sample_Time.Value);
            ModelStep=1/(fs*1000);
            assignin('base', 'ModelStep', ModelStep);
            evalin('base', 'TimeSteps.ModelStep=ModelStep;');
            evalin('base','clear ModelStep');
            MCI_Sampling_Frequency='Set';
            assignin('base', 'MCI_Sampling_Frequency', MCI_Sampling_Frequency);
            evalin('base','config.MCI_Sampling_Frequency=MCI_Sampling_Frequency;');
            evalin('base','clear MCI_Sampling_Frequency');
            %%
            BPM_Model_Executable_GroupSelectionChanged(app,event);
            %%
            updateModelWS('MCU_Simulation_Architecture');
            
            
        end

        % Value changed function: Trotor
        function Trotor_Callback(app, event)
            % hObject    handle to Trotor (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Trotor as text
            %        str2double(get(hObject,'String')) returns contents of Trotor as a double
            Trotor= str2double(app.Trotor.Value);
            assignin('base', 'Trotor', Trotor);
            evalin('base','Thermal.Trotor=Trotor;');
            evalin('base','clear Trotor');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: Tstator
        function Tstator_Callback(app, event)
            % hObject    handle to Tstator (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of Tstator as text
            %        str2double(get(hObject,'String')) returns contents of Tstator as a double
            Tstator= str2double(app.Tstator.Value);
            assignin('base', 'Tstator', Tstator);
            evalin('base','Thermal.Tstator=Tstator;');
            evalin('base','clear Tstator');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Button pushed function: cntrl_source
        function cntrl_source_Callback(app, event)
            % hObject    handle to cntrl_source (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Root_Path=uigetdir('C:\');
            if(Root_Path==0)   % Handler if Cancel is Pressed while selecting Path
                msgbox('Please Set Motor Control Source Code Path');
                Motor_Control_Source='Not Set';
            else
                Motor_Control_Source='Set';
            end
            assignin('base', 'Root_Path', Root_Path);
            evalin('base','Paths.Root_Path=Root_Path;');
            evalin('base','clear Root_Path;');
            Object_Path='';
            assignin('base', 'Object_Path', Object_Path);
            evalin('base','Paths.Object_Path=Object_Path;');
            evalin('base','clear Object_Path;');
            assignin('base', 'Motor_Control_Source', Motor_Control_Source);
            evalin('base','config.Motor_Control_Source=Motor_Control_Source;');
            evalin('base','clear Motor_Control_Source');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Button pushed function: druminertia
        function druminertia_Callback(app, event)
            % hObject    handle to druminertia (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            M=evalin('base','Mechanical.M');
            M_D=evalin('base','Mechanical.M_D');
            R_Drum=evalin('base','Mechanical.R_Drum');
            R_J=evalin('base','Mechanical.R_J');
            JDrum= 0.5*(M_D+M)*(R_Drum^2 + (R_Drum-R_J)^2);
            assignin('base','JDrum',JDrum);
            evalin('base','Mechanical.JDrum=JDrum;');
            evalin('base','clear JDrum');
            app.drumJ.Value = num2str(JDrum);
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: drummass
        function drummass_Callback(app, event)
            % hObject    handle to drummass (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of drummass as text
            %        str2double(get(hObject,'String')) returns contents of drummass as a double
            M_D= str2double(app.drummass.Value);
            assignin('base', 'M_D', M_D);
            evalin('base', 'Mechanical.M_D=M_D;');
            evalin('base','clear M_D');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: drumradius
        function drumradius_Callback(app, event)
            % hObject    handle to drumradius (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of drumradius as text
            %        str2double(get(hObject,'String')) returns contents of drumradius as a double
            R_Drum= str2double(app.drumradius.Value);
            assignin('base', 'R_Drum', R_Drum);
            evalin('base', 'Mechanical.R_Drum= R_Drum;');
            evalin('base','clear R_Drum');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: inertiamass
        function inertiamass_Callback(app, event)
            % hObject    handle to inertiamass (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of inertiamass as text
            %        str2double(get(hObject,'String')) returns contents of inertiamass as a double
            M= str2double(app.inertiamass.Value);
            assignin('base', 'M', M);
            evalin('base', 'Mechanical.M=M;');
            evalin('base','clear M');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Callback function
        function obj_src_Callback(app, event)
            % hObject    handle to obj_src (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Object_Path=uigetdir('C:\');
            if(Object_Path==0)   % Handler if Cancel is Pressed while selecting Path
                msgbox('Please Set Motor Control Object Path');
                Motor_Control_Source='Not Set';
            else
                Motor_Control_Source='Set';
            end
            assignin('base', 'Object_Path', Object_Path);
            evalin('base','Paths.Object_Path=Object_Path;');
            evalin('base','clear Object_Path;');
            Root_Path='';
            assignin('base', 'Root_Path', Root_Path);
            evalin('base','Paths.Root_Path=Root_Path;');
            evalin('base','clear Root_Path;');
            assignin('base', 'Motor_Control_Source', Motor_Control_Source);
            evalin('base','config.Motor_Control_Source=Motor_Control_Source;');
            evalin('base','clear Motor_Control_Source');
        end

        % Callback function
        function saveworkspace_Callback(app, event)
            % hObject    handle to saveworkspace (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % [file, path]=uiputfile('*.mat', 'Save Data');
            % complete_file = fullfile(path, file);
            % evalin('base', ['save(''', complete_file ''')'])
            %----- Provision for User to Provide MAT- File Name ----%
            prompt = {'Enter Mat File Name'};
            dlg_title = 'Save Base Workspace';
            prompt = {'Enter Mat File Name'};
            dlg_title = 'Save Base Workspace';
            answer = inputdlg(prompt,dlg_title);
            path='Saved Workspace';
            complete_file = char(fullfile(path, answer));
            evalin('base', ['save(''', complete_file ''')'])
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Button pushed function: simulatemodel
        function simulatemodel_Callback(app, event)
            % hObject    handle to simulatemodel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            Motor_Control_Source= evalin('base', 'config.Motor_Control_Source');
            MCI_Sampling_Frequency= evalin('base','config.MCI_Sampling_Frequency');
            Setting_File_Path = evalin('base', 'Paths.Setting_File_Path');
            Setting_File_Enable = app.SettingFile_Enable.Value;
            
            if (Setting_File_Enable == 1) && strcmp(Setting_File_Path,'')
                msgbox('Please Select the Setting File or Disable it in the Checkbox');
                else
            if strcmp(Motor_Control_Source,'Set')
                if strcmp(MCI_Sampling_Frequency,'Set')
                    open('MCU_Simulation_Architecture.slx');
                    tic;
                    sim('MCU_Simulation_Architecture.slx');
                    toc;
                else
                    g=msgbox('Please Set MCI Sampling Frequency');
                end
            else
                h = msgbox('Please Select Source for Motor Control');
            end
            end
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: thickness
        function thickness_Callback(app, event)
            % hObject    handle to thickness (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of thickness as text
            %        str2double(get(hObject,'String')) returns contents of thickness as a double
            R_J= str2double(app.thickness.Value);
            assignin('base', 'R_J', R_J);
            evalin('base', 'Mechanical.R_J= R_J;');
            evalin('base','clear R_J');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: MotorList_CIM
        function MotorList_CIMValueChanged(app, event)
            LoadSelectionTabSelectionChanged(app, event);             

        end

        % Selection changed function: BPM_Motor_Type_Group
        function BPM_Motor_Type_GroupSelectionChanged(app, event)
            app.BeltRatioHA.Enable = 'on';
            selectedButton = app.BPM_Motor_Type_Group.SelectedObject;
            if strcmp(selectedButton.Text, 'sBPM')
                app.MotorList_BPM.UserData = 'ParametersFiles\Motor_Parameters\BPM\sBPM';
            elseif strcmp(selectedButton.Text, 'gBPM')
                app.MotorList_BPM.UserData = 'ParametersFiles\Motor_Parameters\BPM\gBPM';
            elseif strcmp(selectedButton.Text, 'DD BPM')
                app.MotorList_BPM.UserData = 'ParametersFiles\Motor_Parameters\BPM\DDBPM';
                app.BeltRatioHA.Enable = 'off';
            end
            dinfo = dir( fullfile(app.MotorList_BPM.UserData, '*.xml') );
            script_names = {dinfo.name};
            app.MotorList_BPM.Items = script_names;
            MotorList_BPM_Callback(app,event);
            
        end

        % Selection change function: TabGroupMotor
        function TabGroupMotorSelectionChanged(app, event)
            selectedTab = app.TabGroupMotor.SelectedTab;
            if strcmp(selectedTab.Title, 'BPM')
                BPM_Motor_Type_GroupSelectionChanged(app, event);    
                evalin('base', 'Variant_Sensor=0;');
            elseif strcmp(selectedTab.Title, 'CIM')
                MotorList_CIMValueChanged(app, event);
                evalin('base', 'Variant_Sensor=1;');
            end
            
        end

        % Value changed function: RectifierList_120
        function RectifierList_120ValueChanged(app, event)
            chosen_file = app.RectifierList_120.Value;
            complete_file_R = fullfile( 'ParametersFiles\Board_Parameters\HVPS_120V', chosen_file);
            assignin('base', 'complete_file_R',complete_file_R);
            complete_file_R=evalin('base','complete_file_R');
            evalin('base','Paths.complete_file_R=complete_file_R;');
            evalin('base','clear complete_file_R');
            run_rect_xml(complete_file_R);
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: RectifierList_120half
        function RectifierList_120halfValueChanged(app, event)
            chosen_file = app.RectifierList_120half.Value;
            complete_file_R = fullfile( 'ParametersFiles\Board_Parameters\HVPS_120V_HalfWave', chosen_file);
            assignin('base', 'complete_file_R',complete_file_R);
            complete_file_R=evalin('base','complete_file_R');
            evalin('base','Paths.complete_file_R=complete_file_R;');
            evalin('base','clear complete_file_R');
            run_rect_xml(complete_file_R);
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            selectedTab = app.TabGroup.SelectedTab;
            if strcmp(selectedTab.Title, '230V')
                RectifierList_230_Callback(app, event); 
            elseif strcmp(selectedTab.Title, '120V')
                RectifierList_120ValueChanged(app, event);
            elseif strcmp(selectedTab.Title, '120V 1/2Wave')
                RectifierList_120halfValueChanged(app, event);
            end
            RectifierButtonGroupSelectionChanged(app, event);
        end

        % Selection changed function: RectifierButtonGroup
        function RectifierButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.RectifierButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Constant DC Bus Voltage')
                DCEditEnable = 'on';
                RectifierEnable = 'off';
                DC1_Callback(app, event);
                assignin('base','Variant_Input_Circuit', 5);
            elseif strcmp(selectedButton.Text, 'Rectifier Model')
                DCEditEnable = 'off';
                RectifierEnable = 'on';
                selectedTab = app.TabGroup.SelectedTab;
                if strcmp(selectedTab.Title, '230V')
                    assignin('base','Variant_Input_Circuit', 4);
                    
                elseif strcmp(selectedTab.Title, '120V')
                    assignin('base','Variant_Input_Circuit', 1);
                    
                elseif strcmp(selectedTab.Title, '120V 1/2Wave')
                    assignin('base','Variant_Input_Circuit', 6);
                    
                end
                RectFreqGroupSelectionChanged(app, event);
            end
            app.DC1.Enable = DCEditEnable;
            app.mci_step.Enable = RectifierEnable;
            app.rect_step_2k.Enable = RectifierEnable;
            app.half_mci_step.Enable = RectifierEnable;
        end

        % Selection changed function: RectFreqGroup
        function RectFreqGroupSelectionChanged(app, event)
            selectedButton = app.RectFreqGroup.SelectedObject;
            if strcmp(selectedButton.Text, '2kHz')
                InputCircuitStep=1/2000;
                    
            elseif strcmp(selectedButton.Text, '0.5*MCI Frequency')
                T=evalin('base','TimeSteps.ModelStep');
                InputCircuitStep=T*2;
                
            elseif strcmp(selectedButton.Text, 'MCI Frequency')
                T=evalin('base','TimeSteps.ModelStep');
                InputCircuitStep=T;
                
            end
            assignin('base', 'InputCircuitStep', InputCircuitStep);
            evalin('base','TimeSteps.InputCircuitStep=InputCircuitStep;');
            evalin('base','clear InputCircuitStep');
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Selection change function: LoadSelectionTab
        function LoadSelectionTabSelectionChanged(app, event)
            assignin('base', 'evento', event);
            selectedTab = app.LoadSelectionTab.SelectedTab;
            if strcmp(selectedTab.Title, 'Dyno')
                assignin('base', 'Variant_Motor', 3);
                evalin('base', 'config.MechanicalVariant=''DynoLoad'';');
                
                m_unb=0;
                assignin('base', 'm_unb', m_unb);
                evalin('base','Mechanical.m_unb=m_unb;');
                evalin('base', 'clear m_unb');
                r_unb=0;
                assignin('base', 'r_unb', r_unb);
                evalin('base','Mechanical.r_unb=r_unb;');
                evalin('base', 'clear r_unb');
                JDrum=0.001;
                assignin('base', 'JDrum', JDrum);
                evalin('base','Mechanical.JDrum=JDrum;');
                evalin('base', 'clear JDrum');
                damp=0;
                assignin('base', 'damp', damp);
                evalin('base','Mechanical.damp=damp;');
                evalin('base', 'clear damp');
                WasherMechanicsTabSelectionChanged(app, event);
                evalin('base', 'Motor.Mechanical.TR = 1;');
                updateModelWS('MCU_Simulation_Architecture');
            elseif strcmp(selectedTab.Title, 'Compressor')
                assignin('base', 'Variant_Motor', 8);
                evalin('base', 'config.MechanicalVariant=''Compressor'';');
                
                m_unb=0;
                assignin('base', 'm_unb', m_unb);
                evalin('base','Mechanical.m_unb=m_unb;');
                evalin('base', 'clear m_unb');
                r_unb=0;
                assignin('base', 'r_unb', r_unb);
                evalin('base','Mechanical.r_unb=r_unb;');
                evalin('base', 'clear r_unb');
                JDrum=0.001;
                assignin('base', 'JDrum', JDrum);
                evalin('base','Mechanical.JDrum=JDrum;');
                evalin('base', 'clear JDrum');
                damp=0;
                assignin('base', 'damp', damp);
                evalin('base','Mechanical.damp=damp;');
                evalin('base', 'clear damp');
                WasherMechanicsTabSelectionChanged(app, event);
                evalin('base', 'Motor.Mechanical.TR = 1;');
                updateModelWS('MCU_Simulation_Architecture');
            elseif strcmp(selectedTab.Title, 'Washer')
                WasherMechanicsTabSelectionChanged(app, event);
            end
        end

        % Selection change function: WasherMechanicsTab
        function WasherMechanicsTabSelectionChanged(app, event)
            if (app.TabGroupMotor.SelectedTab==app.BPMTab)
                chosen_file = app.MotorList_BPM.Value; 
                complete_file_M = fullfile( app.MotorList_BPM.UserData, chosen_file);
                assignin('base', 'complete_file_M',complete_file_M);
                evalin('base','Paths.complete_file_M=complete_file_M;');
                evalin('base','clear complete_file_M');

                run_motor_bpm_xml(complete_file_M);
%                 Enable_Motor_Power_Loss_Callback(app, event);
                Enable_Motor_Power_LossValueChanged(app,event);
  
                
            elseif (app.TabGroupMotor.SelectedTab==app.CIMTab)
                chosen_file = app.MotorList_CIM.Value; 
                complete_file_M = fullfile( 'ParametersFiles\Motor_Parameters\CIM', chosen_file);
                assignin('base', 'complete_file_M',complete_file_M);
                evalin('base','Paths.complete_file_M=complete_file_M;');
                evalin('base','clear complete_file_M');

                run_motor_cim_xml(complete_file_M);

            end
            
            if (app.LoadSelectionTab.SelectedTab == app.WasherTab)
                updateModelWS('MCU_Simulation_Architecture');
                selectedTab = app.WasherMechanicsTab.SelectedTab;
                if strcmp(selectedTab.Title, 'HA')
                    if (app.TabGroupMotor.SelectedTab==app.BPMTab) && (app.BPM_Motor_Type_Group.SelectedObject==app.gbpm)
                        app.BPM_Motor_Type_Group.SelectedObject = app.sbpm;
                        BPM_Motor_Type_GroupSelectionChanged(app, event);
                        app.MessageArea.Value = 'No support for HA mechanics with gBPM motor. Changed motor to sBPM.';
                    end
                    if (app.TabGroupMotor.SelectedTab==app.BPMTab)
                        assignin('base', 'Variant_Motor', 4);
                    elseif (app.TabGroupMotor.SelectedTab==app.CIMTab)
                        assignin('base', 'Variant_Motor', 2);
                    end
                    MechList_HAValueChanged(app, event);    
                    
                elseif strcmp(selectedTab.Title, 'VA')
                    selectedMotor = app.TabGroupMotor.SelectedTab;
                    if (selectedMotor==app.CIMTab)
                        app.TabGroupMotor.SelectedTab = app.BPMTab;
                        TabGroupMotorSelectionChanged(app, event);
                        app.MessageArea.Value = 'No support for VA mechanics with CIM motor. Changed motor to BPM.';
                    end
                    VaModeButtonGroupSelectionChanged(app, event);
                end
            end
        end

        % Value changed function: MechList_HA
        function MechList_HAValueChanged(app, event)
            chosen_file = app.MechList_HA.Value;
            complete_file_mc = fullfile( 'ParametersFiles\Mechanical_Parameters\HA', chosen_file);
            assignin('base', 'complete_file_mc',complete_file_mc);
            complete_file_mc=evalin('base','complete_file_mc');
            evalin('base','Paths.complete_file_mc=complete_file_mc;');
            evalin('base','clear complete_file_mc');
            run_mech_xml(complete_file_mc);
            app.drummass.Value = num2str(evalin('base', 'Mechanical.M_D;'));
            app.inertiamass.Value = num2str(evalin('base', 'Mechanical.M;'));
            app.drumradius.Value = num2str(evalin('base', 'Mechanical.R_Drum;'));
            app.thickness.Value = num2str(evalin('base', 'Mechanical.R_J;'));
            app.BeltRatioHA.Value = evalin('base', 'Motor.Mechanical.TR;');
            updateModelWS('MCU_Simulation_Architecture');
            
        end

        % Selection changed function: VaModeButtonGroup
        function VaModeButtonGroupSelectionChanged(app, event)
            selectedButton = app.VaModeButtonGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'Agitation')
                gear_ratio_enable = 'on';
                app.MechList_VA.UserData = 'Agitation';   
                
                assignin('base','Variant_Motor', 5);    
            elseif strcmp(selectedButton.Text, 'Spin')
                gear_ratio_enable = 'off';
                app.MechList_VA.UserData = 'Spin'; 
                assignin('base','Variant_Motor', 6);
            end
            dinfo = dir( fullfile(['ParametersFiles\Mechanical_Parameters\VA\' app.MechList_VA.UserData], '*.xml') );
            script_names = {dinfo.name};
            app.MechList_VA.Items = script_names;
            
            app.GearRatioEditField.Enable = gear_ratio_enable;
            MechList_VAValueChanged(app, event);
        end

        % Value changed function: MechList_VA
        function MechList_VAValueChanged(app, event)
            app.BeltRatioVA.Enable = 'on';
            app.GearRatioEditField.Enable = 'on';
            
            chosen_file = app.MechList_VA.Value;
            complete_file_mc = fullfile( ['ParametersFiles\Mechanical_Parameters\VA\' app.MechList_VA.UserData], chosen_file);
            assignin('base', 'complete_file_mc',complete_file_mc);
            complete_file_mc=evalin('base','complete_file_mc');
            evalin('base','Paths.complete_file_mc=complete_file_mc;');
            evalin('base','clear complete_file_mc');
            if strcmp(app.MechList_VA.UserData, 'Agitation')
                run_mech_VAagitationxml(complete_file_mc);
                app.BeltRatioVA.Value = evalin('base', 'Mechanical.belt_ratio');
                app.GearRatioEditField.Value = evalin('base', 'Mechanical.gear_ratio');
            else
                run_mech_VAspinxml(complete_file_mc);
%                 app.BeltRatioVA.Value = evalin('base', 'Mechanical.belt_ratio');
                app.GearRatioEditField.Value = 1;
                app.GearRatioEditField.Enable = 'off';
            end
            if app.BPM_Motor_Type_Group.SelectedObject == app.ddbpm
                app.BeltRatioVA.Value = 1;
                app.GearRatioEditField.Value = 1;
                evalin('base', 'Mechanical.belt_ratio = 1;');
                app.BeltRatioVA.Enable = 'off';
                app.GearRatioEditField.Enable = 'off';
                
            end
            updateModelWS('MCU_Simulation_Architecture');
            
        end

        % Value changed function: BeltRatioVA
        function BeltRatioVAValueChanged(app, event)
            evalin('base', ['Mechanical.belt_ratio=' num2str(app.BeltRatioVA.Value) ';']);
                        
        end

        % Value changed function: GearRatioEditField
        function GearRatioEditFieldValueChanged(app, event)
            value = app.GearRatioEditField.Value;
            evalin('base', ['Mechanical.gear_ratio=' num2str(value) ';']);
        end

        % Value changed function: BeltRatioHA
        function BeltRatioHAValueChanged(app, event)
            value = app.BeltRatioHA.Value;
            evalin('base', ['Motor.Mechanical.TR=' num2str(value) ';']);
        end

        % Selection changed function: SimulationModeGroup
        function SimulationModeGroupSelectionChanged(app, event)
            selectedButton = app.SimulationModeGroup.SelectedObject;
            if strcmp(selectedButton.Text, 'SIL- SRMotor+MCI')
                assignin('base','Variant_Motor_Control', 1);
            elseif selectedButton == app.PIL
                assignin('base','Variant_Motor_Control', 2);
            end
        end

        % Selection changed function: Drum
        function DrumSelectionChanged(app, event)
            selectedButton = app.Drum.SelectedObject;
            if selectedButton == app.drumenable
                enabled = 'on';
            else
                enabled = 'off';
                 
            end
            MechList_HAValueChanged(app, event);
            app.drumJ.Enable = enabled;
            app.drummass.Enable = enabled;
            app.inertiamass.Enable = enabled;
            app.drumradius.Enable = enabled;
            app.thickness.Enable = enabled;
            app.druminertia.Enable = enabled;
        end

        % Selection changed function: BPM_Model_Executable_Group
        function BPM_Model_Executable_GroupSelectionChanged(app, event)
            selectedButton = app.BPM_Model_Executable_Group.SelectedObject;
            T = evalin('base','TimeSteps.ModelStep');
%            if ((selectedButton == app.SF) && (T == 6.25e-5) && (app.Enable_High_Speed.Value == 0))
%             Variant_Motor_Model_Executable = 2;
%             assignin('base','Variant_Motor_Model_Executable', Variant_Motor_Model_Executable);
%            elseif((selectedButton == app.SF) && (T == 6.25e-5) && (app.Enable_High_Speed.Value == 1))
%             Variant_Motor_Model_Executable = 12;
%             assignin('base','Variant_Motor_Model_Executable',Variant_Motor_Model_Executable);
%            elseif ((selectedButton == app.SF) && (T == 1e-4))
%             Variant_Motor_Model_Executable = 3;
%             assignin('base','Variant_Motor_Model_Executable',Variant_Motor_Model_Executable);
%            elseif ((selectedButton == app.SF) && (T == 2e-04))
%             Variant_Motor_Model_Executable = 4;
%             assignin('base','Variant_Motor_Model_Executable',Variant_Motor_Model_Executable);
           

           if ((selectedButton == app.SF) && (app.Enable_High_Speed.Value == 0))
            Variant_Motor_Model_Executable = 2;
            assignin('base','Variant_Motor_Model_Executable', Variant_Motor_Model_Executable);
           
           elseif((selectedButton == app.SF) && (app.Enable_High_Speed.Value == 1))
            Variant_Motor_Model_Executable = 21;
            assignin('base','Variant_Motor_Model_Executable',Variant_Motor_Model_Executable);
            
           elseif selectedButton == app.FMU
            Variant_Motor_Model_Executable = 1;
            assignin('base','Variant_Motor_Model_Executable',Variant_Motor_Model_Executable);
           
           else
           Variant_Motor_Model_Executable = 2;
           assignin('base','Variant_Motor_Model_Executable',Variant_Motor_Model_Executable); 
           end
           
           updateModelWS('MCU_Simulation_Architecture');
           
           %if selectedButton == app.SF
           % app.MessageArea.Value = 'Supports only 10kHz and 16kHz MCI Sampling Frequencies';
           %elseif selectedButton == app.FMU
           % app.MessageArea.Value = 'Supports all MCI Sampling Frequencies';
           %end
        end

        % Button pushed function: SettingFile_Source
        function SettingFile_SourceButtonPushed(app, event)
            current_path = pwd;
            cd('C:\');
            [file,path] = uigetfile('*.bin');
            if isequal(path,0) %Hander if Cancel is Pressed while selecting Path
                msgbox('Setting File Has Not Been Selected'); 
            end
            cd(current_path);
            Setting_File_Path = fullfile(path,file);
            if (Setting_File_Path == 0)
                Setting_File_Path = '';
            else
                Setting_File_Flag='Set';
                assignin('base', 'Setting_File_Flag',Setting_File_Flag);
                evalin('base','config.Setting_File_Flag=Setting_File_Flag;');
                evalin('base','clear Setting_File_Flag');
            end
            assignin('base', 'Setting_File_Path', Setting_File_Path);
            evalin('base','Paths.Setting_File_Path=Setting_File_Path;');
            evalin('base','clear Setting_File_Path;');

            % script for managing setting file update from simulation environment
            Paths.Setting_File_Path = evalin('base','Paths.Setting_File_Path;');
            Paths.Python_SF_Utilities = '.\tools\gese_utils';
            
            sf_manager_mci;

            assignin('base', 'Motor_Control_Parameters', Motor_Control_Parameters);
            assignin('base', 'table_sf_bin', table_sf_bin);

            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: SettingFile_Enable
        function SettingFile_EnableValueChanged(app, event)
            value = app.SettingFile_Enable.Value;
            if (value == 1)
                set(app.SettingFile_Source,'Enable','on');
            else
                set(app.SettingFile_Source,'Enable','off');
                Setting_File_Flag = 'Not Set';
                assignin('base', 'Setting_File_Flag',Setting_File_Flag);
                evalin('base','config.Setting_File_Flag=Setting_File_Flag;');
                evalin('base','clear Setting_File_Flag');
                
                Setting_File_Path = '';
                assignin('base', 'Setting_File_Path', Setting_File_Path);
                evalin('base','Paths.Setting_File_Path=Setting_File_Path;');
                evalin('base','clear Setting_File_Path;');
            end
            updateModelWS('MCU_Simulation_Architecture');
        end

        % Value changed function: Enable_High_Speed
        function Enable_High_SpeedValueChanged(app, event)
            %if (app.Enable_High_Speed.Value == 1)
            %    High_Speed_Enable=1;
            %else
            %    High_Speed_Enable=0;
            %end
            %assignin('base', 'High_Speed_Enable', High_Speed_Enable);
            BPM_Model_Executable_GroupSelectionChanged(app,event);
            %Enable_Motor_Power_Loss_Callback(app,event);
            Enable_Motor_Power_LossValueChanged(app,event);
            %evalin('base','Motor.Power_Loss.Rot_Loss_Flag=Rot_Loss_Enable;');
            %evalin('base','clear Rot_Loss_Enable');
            updateModelWS('MCU_Simulation_Architecture');
            
        end

        % Value changed function: Enable_Motor_Power_Loss
        function Enable_Motor_Power_LossValueChanged(app, event)
            %value = app.Enable_Motor_Power_Loss.Value;
            if (app.Enable_Motor_Power_Loss.Value == 1)
                Rot_Loss_Enable=1;
            else
                Rot_Loss_Enable=0;
            end
            assignin('base', 'Rot_Loss_Enable',Rot_Loss_Enable);
            evalin('base','Motor.Power_Loss.Rot_Loss_Flag=Rot_Loss_Enable;');
            evalin('base','clear Rot_Loss_Enable');
            updateModelWS('MCU_Simulation_Architecture');
            
        end

        % Selection changed function: InverterVariants
        function InverterVariantsSelectionChanged(app, event)
            selectedButton = app.InverterVariants.SelectedObject;

            if strcmp(selectedButton.Text, 'Ideal')
                assignin('base','Variant_Inverter', 1);
            elseif strcmp(selectedButton.Text, 'Mean Value Losses')
                assignin('base','Variant_Inverter', 2);
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Configure';
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'Motor Control Transient Simulation Architecture';
            app.SimulationModeGroup = uibuttongroup(app.figure1);
            app.SimulationModeGroup.SelectionChangedFcn = createCallbackFcn(app, @SimulationModeGroupSelectionChanged, true);
            app.SIL = uiradiobutton(app.SimulationModeGroup);
            app.SIL.Text = 'SIL- SRMotor+MCI';
            app.SIL.Value = true;
            app.PIL = uiradiobutton(app.SimulationModeGroup);
            app.PIL.Text = 'PIL-SRMotor+MCI';
            app.simulatemodel = uibutton(app.figure1, 'push');
            app.simulatemodel.ButtonPushedFcn = createCallbackFcn(app, @simulatemodel_Callback, true);
            app.simulatemodel.Text = 'Simulate Base Model';
            app.sample_time = uibuttongroup(app.figure1);
            app.sample_time.Title = 'MCI Sampling Frequency';
            app.Simulation_Sample_Time = uieditfield(app.sample_time, 'text');
            app.Simulation_Sample_Time.ValueChangedFcn = createCallbackFcn(app, @Simulation_Sample_Time_Callback, true);
            app.text21 = uilabel(app.sample_time);
            app.text21.Text = 'kHz';
            app.Motor_Control_Options = uibuttongroup(app.figure1);
            app.Motor_Control_Options.Title = 'Motor Control Options';
            app.ADC_Initialization = uicheckbox(app.Motor_Control_Options);
            app.ADC_Initialization.ValueChangedFcn = createCallbackFcn(app, @ADC_Initialization_Callback, true);
            app.ADC_Initialization.Text = 'Fast ADC Initialization';
            app.Motor_Moving_handler = uicheckbox(app.Motor_Control_Options);
            app.Motor_Moving_handler.ValueChangedFcn = createCallbackFcn(app, @Motor_Moving_handler_Callback, true);
            app.Motor_Moving_handler.Text = 'Skip Motor Moving Handler';
            app.TabGroup = uitabgroup(app.figure1);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.VTab = uitab(app.TabGroup);
            app.VTab.Title = '230V';
            app.RectifierList_230 = uilistbox(app.VTab);
            app.RectifierList_230.Items = {'Rectifier List'};
            app.RectifierList_230.ValueChangedFcn = createCallbackFcn(app, @RectifierList_230_Callback, true);
            app.RectifierList_230.Value = 'Rectifier List';
            app.VTab_2 = uitab(app.TabGroup);
            app.VTab_2.Title = '120V';
            app.RectifierList_120 = uilistbox(app.VTab_2);
            app.RectifierList_120.Items = {'Rectifier List'};
            app.RectifierList_120.ValueChangedFcn = createCallbackFcn(app, @RectifierList_120ValueChanged, true);
            app.RectifierList_120.Value = 'Rectifier List';
            app.V12WaveTab = uitab(app.TabGroup);
            app.V12WaveTab.Title = '120V 1/2Wave';
            app.RectifierList_120half = uilistbox(app.V12WaveTab);
            app.RectifierList_120half.Items = {'Rectifier List'};
            app.RectifierList_120half.ValueChangedFcn = createCallbackFcn(app, @RectifierList_120halfValueChanged, true);
            app.RectifierList_120half.Value = 'Rectifier List';
            app.BoardSelectionLabel = uilabel(app.figure1);
            app.BoardSelectionLabel.Text = 'Board Selection';
            app.RectifierButtonGroup = uibuttongroup(app.figure1);
            app.RectifierButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RectifierButtonGroupSelectionChanged, true);
            app.DCBus = uiradiobutton(app.RectifierButtonGroup);
            app.DCBus.Text = 'Constant DC Bus Voltage';
            app.DCBus.Value = true;
            app.DC1 = uieditfield(app.RectifierButtonGroup, 'text');
            app.DC1.ValueChangedFcn = createCallbackFcn(app, @DC1_Callback, true);
            app.text13 = uilabel(app.RectifierButtonGroup);
            app.text13.Text = 'V';
            app.Rectifier = uiradiobutton(app.RectifierButtonGroup);
            app.Rectifier.Text = 'Rectifier Model';
            app.RectFreqGroup = uibuttongroup(app.RectifierButtonGroup);
            app.RectFreqGroup.SelectionChangedFcn = createCallbackFcn(app, @RectFreqGroupSelectionChanged, true);
            app.RectFreqGroup.Enable = 'off';
            app.RectFreqGroup.Title = 'Rectifier Model  Frequency';
            app.mci_step = uiradiobutton(app.RectFreqGroup);
            app.mci_step.Text = 'MCI Frequency';
            app.mci_step.Value = true;
            app.half_mci_step = uiradiobutton(app.RectFreqGroup);
            app.half_mci_step.Text = '0.5*MCI Frequency';
            app.rect_step_2k = uiradiobutton(app.RectFreqGroup);
            app.rect_step_2k.Text = '2kHz';
            app.Panel = uipanel(app.figure1);
            app.cntrl_source = uibutton(app.Panel, 'push');
            app.cntrl_source.ButtonPushedFcn = createCallbackFcn(app, @cntrl_source_Callback, true);
            app.cntrl_source.Text = 'Select Source';
            app.SimulationModeLabel = uilabel(app.figure1);
            app.SimulationModeLabel.Text = 'Simulation Mode';
            app.MCISourceCodeLabel = uilabel(app.figure1);
            app.MCISourceCodeLabel.Text = 'MCI Source Code';
            app.InputCircuitVariantsLabel = uilabel(app.figure1);
            app.InputCircuitVariantsLabel.Text = 'Input Circuit Variants';
            app.TabGroupMotor = uitabgroup(app.figure1);
            app.TabGroupMotor.SelectionChangedFcn = createCallbackFcn(app, @TabGroupMotorSelectionChanged, true);
            app.BPMTab = uitab(app.TabGroupMotor);
            app.BPMTab.Title = 'BPM';
            app.BPM_Motor_Type_Group = uibuttongroup(app.BPMTab);
            app.BPM_Motor_Type_Group.SelectionChangedFcn = createCallbackFcn(app, @BPM_Motor_Type_GroupSelectionChanged, true);
            app.BPM_Motor_Type_Group.Title = 'BPM Variant';
            app.ddbpm = uiradiobutton(app.BPM_Motor_Type_Group);
            app.ddbpm.Text = 'DD BPM';
            app.ddbpm.Value = true;
            app.sbpm = uiradiobutton(app.BPM_Motor_Type_Group);
            app.sbpm.Text = 'sBPM';
            app.gbpm = uiradiobutton(app.BPM_Motor_Type_Group);
            app.gbpm.Text = 'gBPM';
            app.MotorList_BPM = uilistbox(app.BPMTab);
            app.MotorList_BPM.Items = {'Motor List'};
            app.MotorList_BPM.ValueChangedFcn = createCallbackFcn(app, @MotorList_BPM_Callback, true);
            app.MotorList_BPM.Value = 'Motor List';
            app.uibuttongroup32 = uibuttongroup(app.BPMTab);
            app.uibuttongroup32.Title = 'Motor Power Loss';
            app.Enable_Motor_Power_Loss = uicheckbox(app.BPMTab);
            app.Enable_Motor_Power_Loss.ValueChangedFcn = createCallbackFcn(app, @Enable_Motor_Power_LossValueChanged, true);
            app.Enable_Motor_Power_Loss.Text = 'Enabled';
            app.BPM_Model_Executable_Group = uibuttongroup(app.BPMTab);
            app.BPM_Model_Executable_Group.SelectionChangedFcn = createCallbackFcn(app, @BPM_Model_Executable_GroupSelectionChanged, true);
            app.BPM_Model_Executable_Group.Title = 'BPM Model Executable Variant';
            app.FMU = uiradiobutton(app.BPM_Model_Executable_Group);
            app.FMU.Text = 'FMU';
            app.FMU.Value = true;
            app.SF = uiradiobutton(app.BPM_Model_Executable_Group);
            app.SF.Text = 'S-Function';
            app.uibuttongroup32_2 = uibuttongroup(app.BPMTab);
            app.uibuttongroup32_2.Title = 'High Speed Range';
            app.Enable_High_Speed = uicheckbox(app.BPMTab);
            app.Enable_High_Speed.ValueChangedFcn = createCallbackFcn(app, @Enable_High_SpeedValueChanged, true);
            app.Enable_High_Speed.Text = 'Enabled';
            app.CIMTab = uitab(app.TabGroupMotor);
            app.CIMTab.Title = 'CIM';
            app.MotorList_CIM = uilistbox(app.CIMTab);
            app.MotorList_CIM.Items = {'Motor List'};
            app.MotorList_CIM.ValueChangedFcn = createCallbackFcn(app, @MotorList_CIMValueChanged, true);
            app.MotorList_CIM.Value = 'Motor List';
            app.MotorSelectionLabel = uilabel(app.figure1);
            app.MotorSelectionLabel.Text = 'Motor Selection';
            app.uibuttongroup25 = uibuttongroup(app.figure1);
            app.uibuttongroup25.Title = 'Motor Temperature';
            app.text20 = uilabel(app.uibuttongroup25);
            app.text20.Text = 'DegC';
            app.DegC = uilabel(app.uibuttongroup25);
            app.DegC.Text = 'DegC';
            app.Trotor = uieditfield(app.uibuttongroup25, 'text');
            app.Trotor.ValueChangedFcn = createCallbackFcn(app, @Trotor_Callback, true);
            app.Tr = uilabel(app.uibuttongroup25);
            app.Tr.Text = 'Trotor';
            app.Tstator = uieditfield(app.uibuttongroup25, 'text');
            app.Tstator.ValueChangedFcn = createCallbackFcn(app, @Tstator_Callback, true);
            app.Ts = uilabel(app.uibuttongroup25);
            app.Ts.Text = 'Tstator';
            app.LoadSelectionTab = uitabgroup(app.figure1);
            app.LoadSelectionTab.SelectionChangedFcn = createCallbackFcn(app, @LoadSelectionTabSelectionChanged, true);
            app.DynoTab = uitab(app.LoadSelectionTab);
            app.DynoTab.Title = 'Dyno';
            app.LoadprofilecanbedefinedonthemodelLabel = uilabel(app.DynoTab);
            app.LoadprofilecanbedefinedonthemodelLabel.Text = 'Load profile can be defined on the model';
            app.WasherTab = uitab(app.LoadSelectionTab);
            app.WasherTab.Title = 'Washer';
            app.WasherMechanicsTab = uitabgroup(app.WasherTab);
            app.WasherMechanicsTab.SelectionChangedFcn = createCallbackFcn(app, @WasherMechanicsTabSelectionChanged, true);
            app.HATab = uitab(app.WasherMechanicsTab);
            app.HATab.Title = 'HA';
            app.MechList_HA = uilistbox(app.HATab);
            app.MechList_HA.Items = {'Mechanical List'};
            app.MechList_HA.ValueChangedFcn = createCallbackFcn(app, @MechList_HAValueChanged, true);
            app.MechList_HA.Value = 'Mechanical List';
            app.Drum = uibuttongroup(app.HATab);
            app.Drum.SelectionChangedFcn = createCallbackFcn(app, @DrumSelectionChanged, true);
            app.Drum.Title = 'Drum Properties';
            app.text4 = uilabel(app.Drum);
            app.text4.Text = 'Kg';
            app.drumJ = uieditfield(app.Drum, 'text');
            app.druminertia = uibutton(app.Drum, 'push');
            app.druminertia.ButtonPushedFcn = createCallbackFcn(app, @druminertia_Callback, true);
            app.druminertia.Text = 'Calculate Drum Inertia';
            app.text11 = uilabel(app.Drum);
            app.text11.Text = 'm';
            app.thickness = uieditfield(app.Drum, 'text');
            app.thickness.ValueChangedFcn = createCallbackFcn(app, @thickness_Callback, true);
            app.text10 = uilabel(app.Drum);
            app.text10.Text = 'Thickness of Inertial Mass (R_J)';
            app.text9 = uilabel(app.Drum);
            app.text9.Text = 'm';
            app.drumradius = uieditfield(app.Drum, 'text');
            app.drumradius.ValueChangedFcn = createCallbackFcn(app, @drumradius_Callback, true);
            app.text8 = uilabel(app.Drum);
            app.text8.Text = 'Radius of Drum(R_Drum)';
            app.text6 = uilabel(app.Drum);
            app.text6.Text = 'Kg';
            app.inertiamass = uieditfield(app.Drum, 'text');
            app.inertiamass.ValueChangedFcn = createCallbackFcn(app, @inertiamass_Callback, true);
            app.text5 = uilabel(app.Drum);
            app.text5.Text = 'Weight of Inertial Mass(M)';
            app.drummass = uieditfield(app.Drum, 'text');
            app.drummass.ValueChangedFcn = createCallbackFcn(app, @drummass_Callback, true);
            app.text3 = uilabel(app.Drum);
            app.text3.Text = 'Weight of Drum(M_D)';
            app.drumdisable = uiradiobutton(app.Drum);
            app.drumdisable.Text = 'Disable';
            app.drumdisable.Value = true;
            app.drumenable = uiradiobutton(app.Drum);
            app.drumenable.Text = 'Enable';
            app.HAModeButtonGroup = uibuttongroup(app.HATab);
            app.HAModeButtonGroup.Title = 'HA Mode';
            app.TumblingButton = uiradiobutton(app.HAModeButtonGroup);
            app.TumblingButton.Enable = 'off';
            app.TumblingButton.Text = 'Tumbling';
            app.SpinButton = uiradiobutton(app.HAModeButtonGroup);
            app.SpinButton.Text = 'Spin';
            app.SpinButton.Value = true;
            app.BeltRatioEditFieldLabel = uilabel(app.HATab);
            app.BeltRatioEditFieldLabel.Text = 'Belt Ratio';
            app.BeltRatioHA = uieditfield(app.HATab, 'numeric');
            app.BeltRatioHA.ValueChangedFcn = createCallbackFcn(app, @BeltRatioHAValueChanged, true);
            app.VATab = uitab(app.WasherMechanicsTab);
            app.VATab.Title = 'VA';
            app.VaModeButtonGroup = uibuttongroup(app.VATab);
            app.VaModeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @VaModeButtonGroupSelectionChanged, true);
            app.VaModeButtonGroup.Title = 'VA Mode';
            app.agitation = uiradiobutton(app.VaModeButtonGroup);
            app.agitation.Text = 'Agitation';
            app.agitation.Value = true;
            app.spin = uiradiobutton(app.VaModeButtonGroup);
            app.spin.Text = 'Spin';
            app.MechList_VA = uilistbox(app.VATab);
            app.MechList_VA.Items = {'Mechanical List'};
            app.MechList_VA.ValueChangedFcn = createCallbackFcn(app, @MechList_VAValueChanged, true);
            app.MechList_VA.Value = 'Mechanical List';
            app.GearRatioEditFieldLabel = uilabel(app.VATab);
            app.GearRatioEditFieldLabel.Text = 'Gear Ratio';
            app.GearRatioEditField = uieditfield(app.VATab, 'numeric');
            app.GearRatioEditField.ValueChangedFcn = createCallbackFcn(app, @GearRatioEditFieldValueChanged, true);
            app.BeltRatioEditField_2Label = uilabel(app.VATab);
            app.BeltRatioEditField_2Label.Text = 'Belt Ratio';
            app.BeltRatioVA = uieditfield(app.VATab, 'numeric');
            app.BeltRatioVA.ValueChangedFcn = createCallbackFcn(app, @BeltRatioVAValueChanged, true);
            app.CompressorTab = uitab(app.LoadSelectionTab);
            app.CompressorTab.Title = 'Compressor';
            app.LoadSelectionLabel = uilabel(app.figure1);
            app.LoadSelectionLabel.Text = 'Load Selection';
            app.MessageArea = uitextarea(app.figure1);
            app.MessageArea.Editable = 'off';
            app.SettingFileLabel = uilabel(app.figure1);
            app.SettingFileLabel.Text = 'Setting File';
            app.SettingFile_Panel = uipanel(app.figure1);
            app.SettingFile_Source = uibutton(app.SettingFile_Panel, 'push');
            app.SettingFile_Source.ButtonPushedFcn = createCallbackFcn(app, @SettingFile_SourceButtonPushed, true);
            app.SettingFile_Source.Text = 'Select File';
            app.SettingFile_Enable = uicheckbox(app.SettingFile_Panel);
            app.SettingFile_Enable.ValueChangedFcn = createCallbackFcn(app, @SettingFile_EnableValueChanged, true);
            app.SettingFile_Enable.Text = 'Setting File Enabled';
            app.InverterVariantsLabel = uilabel(app.figure1);
            app.InverterVariantsLabel.Text = 'Inverter Variants';
            app.InverterVariants = uibuttongroup(app.figure1);
            app.InverterVariants.SelectionChangedFcn = createCallbackFcn(app, @InverterVariantsSelectionChanged, true);
            app.IdealInverter = uiradiobutton(app.InverterVariants);
            app.IdealInverter.Text = 'Ideal';
            app.IdealInverter.Value = true;
            app.MeanValueLosses = uiradiobutton(app.InverterVariants);
            app.MeanValueLosses.Text = 'Mean Value Losses';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sim_Arch_Config_UI_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Sim_Arch_Config_UI_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end